classdef app1_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        VelocityCheck               matlab.ui.control.CheckBox
        MassCheck                   matlab.ui.control.CheckBox
        NstepsSpinner               matlab.ui.control.Spinner
        NstepsSpinnerLabel          matlab.ui.control.Label
        NbodiesSpinner              matlab.ui.control.Spinner
        NbodiesSpinnerLabel         matlab.ui.control.Label
        SizevarietySlider           matlab.ui.control.Slider
        SizevarietySliderLabel      matlab.ui.control.Label
        MassvarietySlider           matlab.ui.control.Slider
        VelocityvarietySlider       matlab.ui.control.Slider
        MassvarietySliderLabel      matlab.ui.control.Label
        VelocityvarietySliderLabel  matlab.ui.control.Label
        VelocitySlider              matlab.ui.control.Slider
        VelocitySliderLabel         matlab.ui.control.Label
        SizeSlider                  matlab.ui.control.Slider
        SizeSliderLabel             matlab.ui.control.Label
        MassSlider                  matlab.ui.control.Slider
        MassSliderLabel             matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        STARTButton                 matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function draw_circle(app, r, x, y, color)
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            plot(app.UIAxes, xunit, yunit, color, 'LineWidth', 3);
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %% Initialisation
            app.TextArea.Value = '';
            hold(app.UIAxes, "on");
            axis(app.UIAxes, "equal");
            xmax = 10000; ymax = 3500;
            xlim(app.UIAxes, [0 xmax])
            ylim(app.UIAxes, [0 ymax])

            % initialize arrays and values
            N_bodies = app.NbodiesSpinner.Value;
            N_steps = app.NstepsSpinner.Value;
            R = floor(app.SizeSlider.Value);
            M = floor(app.MassSlider.Value);
            V = floor(app.VelocitySlider.Value);
            R_range = floor(app.SizevarietySlider.Value);
            M_range = floor(app.MassvarietySlider.Value);
            V_range = floor(app.VelocityvarietySlider.Value);

            position = zeros(N_bodies, 2); % x, y position of all bodies
            init_position = zeros(N_bodies, 2); % for MSD
            msd = zeros(1,1);
            velocity = zeros(N_bodies, 2); % x, y, velocity of all bodies
            radius = zeros(N_bodies, 1);
            velocity_scalar = zeros(N_bodies, 1);
            mass = zeros(N_bodies, 1);

            % initialize all circles
            cla(app.UIAxes);
            debug = 0;
            flag = 0;
            for i_body = 1:N_bodies
                % random mass, velocity scalar and radius - with given
                % degrees of randomness
                radius(i_body) = randi([max(R-R_range, 1) R+R_range]);
                mass(i_body) = randi([max(M-M_range, 1) M+M_range]);
                velocity_scalar(i_body) = randi([max(V-V_range, 1) V+V_range]);

                % max 100 tries to generate random placement not
                % overlapping with others
                for i_try = 1:200
                    new_pos_x = randi([radius(i_body), xmax-radius(i_body)]);
                    new_pos_y = randi([radius(i_body), ymax-radius(i_body)]);
                    overlap = false;
                    for other = 1:i_body
                        if (other ~= i_body)
                            if(sqrt((position(other,1) - new_pos_x)^2 + (position(other,2) - new_pos_y)^2) <= radius(i_body)+radius(other))
                                overlap = true;
                                break
                            end
                        end
                    end
                    if(~overlap)
                        app.draw_circle(radius(i_body), new_pos_x, new_pos_y, 'b');
                        % set position
                        position(i_body,:) = [new_pos_x, new_pos_y];

                        % set velocity - the value is always the same, but
                        % the initial direction is random (triangle's
                        % equation)
                        new_v_x = randi([-velocity_scalar(i_body) velocity_scalar(i_body)]);
                        % can move upwards or downwards - randomly generate 1 or -1
                        pos = randi(2);
                        sign_arr = [-1 1];
                        sign = sign_arr(pos);
                        velocity(i_body,:) = [new_v_x, sign * sqrt(velocity_scalar(i_body)^2 - new_v_x^2)];
                        debug = debug + 1;
                        break
                    end
                end
                if (debug < i_body) 
                    app.TextArea.FontColor = 'r';
                    app.TextArea.Value = 'TOO MANY BODIES OR TOO LARGE RADIUS';
                    flag = 1;
                end
            end
            
            if(flag) 
                return 
            end
            
            hold(app.UIAxes, "off");
            init_position = position;


            %% Simulation
            for n_steps = 1:N_steps
                %% Collisions with one another
                colliding = [];
                for i_body = 1:N_bodies
                    for other = 1:i_body
                        if(other ~= i_body)
                            % detect collision
                            dist = sqrt((position(other,1) - position(i_body,1))^2 + (position(other,2) - position(i_body,2))^2);
                            if(dist <= radius(i_body)+radius(other))
                                % if collision isn't already detected
                                if (isempty(colliding) || ...
                                        ~any(any(colliding == [other, i_body])))
                                    colliding(end+1,:) = [i_body, other];
                                end
                            end
                        end
                    end
                end
    
                % collisions processed in pairs (velocity) -> if there is a collision
                % between more than two bodies, the collision between B and C 
                % relies on information that you only have after you've processed the collision reponse from A and B
                if(~isempty(colliding))
                    for pair = 1:length(colliding(:,1))
                        p1 = position(colliding(pair,1),:); p2 = position(colliding(pair,2),:);
                        v1 = velocity(colliding(pair,1),:); v2 = velocity(colliding(pair,2),:);
                        m1 = mass(colliding(pair,1),:); m2 = mass(colliding(pair,2),:);
                        velocity(colliding(pair,1),:) = v1 - 2*m2/(m1+m2) * dot(v1-v2, p1-p2)/dot((p1-p2),(p1-p2)) * (p1-p2); % dot((p1-p2),(p1-p2)) = norm(p1-p2)^2
                        velocity(colliding(pair,2),:) = v2 - 2*m1/(m1+m2) * dot(v2-v1, p2-p1)/dot((p2-p1),(p2-p1)) * (p2-p1);
                    end
                end


                %% Collisions with wall
                for i_body = 1:N_bodies
                    % detect collision
                    if(xmax - position(i_body,1) <= radius(i_body) || position(i_body,1) <= radius(i_body))
                        velocity(i_body,1) = -velocity(i_body,1);
                    end
                    if(ymax - position(i_body,2) <= radius(i_body) || position(i_body,2) <= radius(i_body))
                        velocity(i_body,2) = -velocity(i_body,2);
                    end
                end
    

                %% Moving
                cla(app.UIAxes);
                hold(app.UIAxes, "on");
                for i_body = 1:N_bodies
                    position(i_body,:) = position(i_body,:) + velocity(i_body,:);

                    if(any(any(colliding == i_body)))
                        app.draw_circle(radius(i_body), position(i_body,1), position(i_body,2), 'r');
                    else
                        app.draw_circle(radius(i_body), position(i_body,1), position(i_body,2), 'b');
                    end
                    if(app.MassCheck.Value == 1)
                        text(app.UIAxes, position(i_body,1)-50, position(i_body,2)+50, num2str(mass(i_body)), 'Color', 'k'); 
                    end
                    if(app.VelocityCheck.Value == 1)
                        plot(app.UIAxes, [position(i_body,1), (position(i_body,1) + velocity(i_body,1)*2)], [position(i_body,2), (position(i_body,2) + velocity(i_body,2)*2)], 'k');
                    end
                end
                title(app.UIAxes, ['Simulation, step ' num2str(n_steps)]);
                pause(0.001)
                hold(app.UIAxes, "off");


            %% Draw MSD
            position2 = (position - init_position) .^ 2;
            sum_x2 = sum(position2(:,1));
            sum_y2 = sum(position2(:,2));
            msd = [msd, (sum_x2 + sum_y2) / N_bodies];
            plot(app.UIAxes2, msd);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulation')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Mean Square Displacement')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'MSE')
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.TextArea = uitextarea(app.UIFigure);
            app.MassSliderLabel = uilabel(app.UIFigure);
            app.MassSliderLabel.Text = 'Mass';
            app.MassSlider = uislider(app.UIFigure);
            app.MassSlider.Limits = [1 100];
            app.MassSlider.MajorTicks = [];
            app.MassSlider.MinorTicks = [];
            app.MassSlider.Value = 10;
            app.SizeSliderLabel = uilabel(app.UIFigure);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.UIFigure);
            app.SizeSlider.Limits = [10 1000];
            app.SizeSlider.MajorTicks = [];
            app.SizeSlider.MinorTicks = [];
            app.SizeSlider.Value = 100;
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [1 100];
            app.VelocitySlider.MajorTicks = [];
            app.VelocitySlider.MinorTicks = [];
            app.VelocitySlider.Value = 10;
            app.VelocityvarietySliderLabel = uilabel(app.UIFigure);
            app.VelocityvarietySliderLabel.Text = 'Velocity variety';
            app.MassvarietySliderLabel = uilabel(app.UIFigure);
            app.MassvarietySliderLabel.Text = 'Mass variety';
            app.VelocityvarietySlider = uislider(app.UIFigure);
            app.VelocityvarietySlider.MajorTicks = [];
            app.VelocityvarietySlider.MinorTicks = [];
            app.MassvarietySlider = uislider(app.UIFigure);
            app.MassvarietySlider.MajorTicks = [];
            app.MassvarietySlider.MinorTicks = [];
            app.SizevarietySliderLabel = uilabel(app.UIFigure);
            app.SizevarietySliderLabel.Text = 'Size variety';
            app.SizevarietySlider = uislider(app.UIFigure);
            app.SizevarietySlider.MajorTicks = [];
            app.SizevarietySlider.MinorTicks = [];
            app.NbodiesSpinnerLabel = uilabel(app.UIFigure);
            app.NbodiesSpinnerLabel.Text = 'N bodies';
            app.NbodiesSpinner = uispinner(app.UIFigure);
            app.NbodiesSpinner.Limits = [1 100];
            app.NbodiesSpinner.Value = 50;
            app.NstepsSpinnerLabel = uilabel(app.UIFigure);
            app.NstepsSpinnerLabel.Text = 'N steps';
            app.NstepsSpinner = uispinner(app.UIFigure);
            app.NstepsSpinner.Limits = [1 Inf];
            app.NstepsSpinner.Value = 30;
            app.MassCheck = uicheckbox(app.UIFigure);
            app.MassCheck.Text = 'Show mass';
            app.VelocityCheck = uicheckbox(app.UIFigure);
            app.VelocityCheck.Text = 'Show velocity';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end